classdef JPEGcompression < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        qfSlider               matlab.ui.control.Slider
        qfSliderLabel          matlab.ui.control.Label
        JPEGcompressionButton  matlab.ui.control.Button
        LoadImageButton        matlab.ui.control.Button
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
  methods (Access = private)
        
        function updateImage(app, imagefile)
            im = imread(imagefile);
            app.LoadImageButton.UserData = im;
            imshow(im,'Parent',app.UIAxes);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            %display uigetfile dialog
            [file, path] = uigetfile({'*.png','PNG Images'});

            %makesure user didn't cancel uigetfile dialog
            if (ischar(path))
                fname = [path file];
                updateImage(app, fname);
            end

        end

        % Button pushed function: JPEGcompressionButton
        function JPEGcompressionButtonPushed(app, event)
            %we need to pass in the input image for further processing, as
            %well as the slider values for scale factor reasons

            qf = app.qfSlider.Value;
            sourceImg = app.LoadImageButton.UserData;

            outputImage = project4(sourceImg, qf);

            h = imshow(outputImage, 'Parent', app.UIAxes2);

            imsave(h);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'JPEG Result')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.JPEGcompressionButton = uibutton(app.UIFigure, 'push');
            app.JPEGcompressionButton.ButtonPushedFcn = createCallbackFcn(app, @JPEGcompressionButtonPushed, true);
            app.JPEGcompressionButton.Text = 'JPEG compression';
            app.qfSliderLabel = uilabel(app.UIFigure);
            app.qfSliderLabel.Text = 'qf';
            app.qfSlider = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JPEGcompression

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end